classdef BackgroundCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BackgroundcorrectionUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        OptionsPanel                  matlab.ui.container.Panel
        StartdrawingButton            matlab.ui.control.Button
        DrawingoptionsButtonGroup     matlab.ui.container.ButtonGroup
        drawButton                    matlab.ui.control.RadioButton
        eraseButton                   matlab.ui.control.RadioButton
        SaveButton                    matlab.ui.control.Button
        StartoverButton               matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        BackgroundlabelingPanel       matlab.ui.container.Panel
        LabelbackgroundButton         matlab.ui.control.Button
        ThresholdSliderLabel          matlab.ui.control.Label
        ThresholdSlider               matlab.ui.control.Slider
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp     % ImageLabeler
        Image
        bcgMask
        
        threshold = 0.1;
        drawOptions = 'draw'
    end
    
    methods (Access = private)
        
        function drawMask(app)
            while true
                updateDisplay(app)
                h = drawfreehand(app.UIAxes, 'DrawingArea', [1 1 size(app.Image, 1) size(app.Image, 2)]);
                mask = createMask(h);
                updateMask(app, mask)
            end
        end
        
        function updateDisplay(app)
            image = app.Image;
            mask = app.bcgMask;
            imOver = labeloverlay(image, mask, 'colormap', 'spring');
            
            imshow(imOver, 'Parent', app.UIAxes)
            imshow(image, 'Parent', app.UIAxes_2)
        end
        
        function updateMask(app, mask)
            if strcmp(app.drawOptions, 'draw')
                app.bcgMask = app.bcgMask | mask;
                
            elseif strcmp(app.drawOptions, 'erase')
                commonPart = app.bcgMask & mask;
                app.bcgMask = app.bcgMask - commonPart;
                
            else
                warndlg('Wrong drawing options');
                
            end
        end        
        
        function BackgroundLabel = labelBackground(app, image)
            se = strel('disk', 3);
            se2 = strel('disk', 5);
            
            I = mat2gray(image);
            bin = imbinarize(I,'adaptive','ForegroundPolarity','dark','Sensitivity', app.threshold);
            closed = imclose(bin, se);
            filled = imfill(closed, 'holes');
            opened = imopen(filled, se2);
            BackgroundLabel = ~opened;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Image, bcgMask)
            % Store main app in property for CloseRequestFcn to use
            app.MainApp = mainapp;
            
            % Update UI with input values
            app.Image = Image;
            app.bcgMask = bcgMask;
            
            imOver = imoverlay(Image, bcgMask, 'm');
            %display image and mask
            imshow(app.Image, 'Parent', app.UIAxes_2);
            imshow(imOver, 'Parent', app.UIAxes);
        end

        % Callback function: BackgroundcorrectionUIFigure, 
        % CancelButton
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.MainApp.ImageLabelerUIFigure.Visible = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: StartoverButton
        function StartoverButtonPushed(app, event)
            %clear the mask
            app.bcgMask = zeros(size(app.Image,1), size(app.Image,2), size(app.Image,3));
            
            updateDisplay(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.MainApp.ImageLabelerUIFigure.Visible = 'on';
            updateBackgroundMask(app.MainApp, app.bcgMask)
            
            delete(app)
        end

        % Selection changed function: DrawingoptionsButtonGroup
        function DrawingoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DrawingoptionsButtonGroup.SelectedObject.Text;
            
            app.drawOptions = selectedButton;
        end

        % Button pushed function: StartdrawingButton
        function StartdrawingButtonPushed(app, event)
            drawMask(app)
        end

        % Button pushed function: LabelbackgroundButton
        function LabelbackgroundButtonPushed(app, event)
            I = app.Image;
            
            app.bcgMask = labelBackground(app, I);
            
            updateDisplay(app);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;            
            app.threshold = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BackgroundcorrectionUIFigure = uifigure('Visible', 'off');
            app.BackgroundcorrectionUIFigure.Name = 'Background correction';
            app.BackgroundcorrectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BackgroundcorrectionUIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.OptionsPanel = uipanel(app.BackgroundcorrectionUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.StartdrawingButton = uibutton(app.OptionsPanel, 'push');
            app.StartdrawingButton.ButtonPushedFcn = createCallbackFcn(app, @StartdrawingButtonPushed, true);
            app.StartdrawingButton.Text = 'Start drawing';
            app.DrawingoptionsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DrawingoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrawingoptionsButtonGroupSelectionChanged, true);
            app.DrawingoptionsButtonGroup.Title = 'Drawing options';
            app.drawButton = uiradiobutton(app.DrawingoptionsButtonGroup);
            app.drawButton.Text = 'draw';
            app.drawButton.Value = true;
            app.eraseButton = uiradiobutton(app.DrawingoptionsButtonGroup);
            app.eraseButton.Text = 'erase';
            app.SaveButton = uibutton(app.OptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StartoverButton = uibutton(app.OptionsPanel, 'push');
            app.StartoverButton.ButtonPushedFcn = createCallbackFcn(app, @StartoverButtonPushed, true);
            app.StartoverButton.Text = 'Start over';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.BackgroundlabelingPanel = uipanel(app.OptionsPanel);
            app.BackgroundlabelingPanel.Title = 'Background labeling';
            app.LabelbackgroundButton = uibutton(app.BackgroundlabelingPanel, 'push');
            app.LabelbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @LabelbackgroundButtonPushed, true);
            app.LabelbackgroundButton.Text = 'Label background';
            app.ThresholdSliderLabel = uilabel(app.BackgroundlabelingPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.BackgroundlabelingPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Value = 0.1;
            app.UIAxes_2 = uiaxes(app.BackgroundcorrectionUIFigure);
            title(app.UIAxes_2, 'Preview')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            % Show the figure after all components are created
            app.BackgroundcorrectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackgroundCorrection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BackgroundcorrectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BackgroundcorrectionUIFigure)
        end
    end
end