classdef ImageLabeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLabelerUIFigure       matlab.ui.Figure
        OptionsPanel               matlab.ui.container.Panel
        LoadimageButton            matlab.ui.control.Button
        BackgroundlabelingPanel    matlab.ui.container.Panel
        LabelallimagesButton       matlab.ui.control.Button
        ThresholdSliderLabel       matlab.ui.control.Label
        ThresholdSlider            matlab.ui.control.Slider
        MakecorrectionsButton      matlab.ui.control.Button
        SavemaskButton             matlab.ui.control.Button
        CategoriesButtonGroup      matlab.ui.container.ButtonGroup
        nucleiButton               matlab.ui.control.RadioButton
        spotButton                 matlab.ui.control.RadioButton
        artefactButton             matlab.ui.control.RadioButton
        backgroundButton           matlab.ui.control.RadioButton
        StartdrawingButton         matlab.ui.control.Button
        LoadmaskButton             matlab.ui.control.Button
        DrawingoptionsButtonGroup  matlab.ui.container.ButtonGroup
        drawButton                 matlab.ui.control.RadioButton
        eraseButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        SlideSliderLabel           matlab.ui.control.Label
        SlideSlider                matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Images = struct('Image', [])    % struct with 3D image
        numberOfImages
        currentImage = 1;
        MaskNuclei
        MaskSpot
        MaskArtefact
        MaskBcg                         %background mask
        AxisHandle

        
        Category = 'nuclei'
        drawOptions = 'draw'
        threshold = 0.1;
        
        CategoryApp
        BackgroundCorrectionApp
        
        selectedCategory = 'nuclei'
    end
    
    methods (Access = public)        
        
        function updateMask(app, mask)
            if strcmp(app.drawOptions, 'draw')
                if strcmp(app.Category, 'nuclei')
                    app.MaskNuclei(:,:,app.currentImage) = app.MaskNuclei(:,:,app.currentImage) | mask;
                elseif strcmp(app.Category, 'spot')
                    app.MaskSpot(:,:,app.currentImage) = app.MaskSpot(:,:,app.currentImage) | mask;
                elseif strcmp(app.Category, 'artefact')
                    app.MaskArtefact(:,:,app.currentImage) = app.MaskArtefact(:,:,app.currentImage) | mask;
                elseif strcmp(app.Category, 'background')
                    app.MaskBcg(:,:,app.currentImage) = app.MaskBcg(:,:,app.currentImage) | mask;
                    
                else
                    warndlg('Wrong categhory');
                end
            elseif strcmp(app.drawOptions, 'erase')
                if strcmp(app.Category, 'nuclei')
                    commonPart = app.MaskNuclei & mask;
                    app.MaskNuclei = app.MaskNuclei - commonPart;
                elseif strcmp(app.Category, 'spot')
                    commonPart = app.MaskSpot & mask;
                    app.MaskSpot = commonPart - app.MaskSpot;
                elseif strcmp(app.Category, 'artefact')
                    commonPart = app.MaskArtefact & mask;
                    app.MaskArtefact = app.MaskArtefact - commonPart;
                elseif strcmp(app.Category, 'background')
                    commonPart = app.MaskBcg & mask;
                    app.MaskBcg = app.MaskBcg - commonPart;                    
                else
                    warndlg('Wrong category');
                end
            else
                warndlg('Wrong drawing options');
            end
        end
        
        function drawMask(app)
            while true
                updateDisplay(app)
                h = drawfreehand(app.UIAxes, 'DrawingArea', [1 1 size(app.Images(1).Image, 1) size(app.Images(1).Image, 2)]);
                mask = createMask(h);
                updateMask(app, mask)
            end
        end
        
        function updateDisplay(app)
            image = app.Images(app.currentImage).Image;
            if strcmp(app.Category, 'nuclei')
                mask = app.MaskNuclei(:,:,app.currentImage);
                imOver = labeloverlay(image, mask);
            elseif strcmp(app.Category, 'spot')
                mask = app.MaskSpot(:,:,app.currentImage);
                imOver = labeloverlay(image, mask, 'Colormap', 'summer');
            elseif strcmp(app.Category, 'artefact')
                mask = app.MaskArtefact(:,:,app.currentImage);
                imOver = labeloverlay(image, mask, 'Colormap','autumn');
            elseif strcmp(app.Category, 'background')
                mask = app.MaskBcg(:,:,app.currentImage);
                imOver = labeloverlay(image, mask, 'Colormap','spring');
            else
                warndlg('Wrong category');
            end
            imshow(imOver, 'Parent', app.UIAxes)
            imshow(image, 'Parent', app.UIAxes2)
        end
        
        function loadMask(app)
            %get file path
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            imPath = fullfile(path, file);
            Masks = load(imPath);

            app.MaskNuclei = Masks.Masks{1};            
            app.MaskSpot = Masks.Masks{2};
            app.MaskArtefact = Masks.Masks{3};            
            app.MaskBcg = Masks.Masks{4};
            
            updateDisplay(app)
        end
        
        function updateBackgroundMask(app, mask)
            app.MaskBcg(:,:,app.currentImage) = mask;
            updateDisplay(app)
        end
        
        function BackgroundLabel = labelBackground(app, image)
            se = strel('disk', 3);
            se2 = strel('disk', 5);
            
            I = mat2gray(image);
            bin = imbinarize(I,'adaptive','ForegroundPolarity','dark','Sensitivity', app.threshold);
            closed = imclose(bin, se);
            filled = imfill(closed, 'holes');
            opened = imopen(filled, se2);
            BackgroundLabel = ~opened;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            %get file path
            [file,path] = uigetfile('*.tif');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            imPath = fullfile(path, file);
            
            app.numberOfImages = size(imfinfo(imPath),1);
            
            %create struct with images
            hWaitBar = waitbar(0,'Reading files...','Name', 'Please wait');
            for i = 1:app.numberOfImages
                app.Images(i).Image = mat2gray(imread(imPath,i));
                waitbar(i/app.numberOfImages)
            end
            delete(hWaitBar);
            
            %display first image on axes Image and Preview
            imshow(app.Images(1).Image, 'Parent', app.UIAxes2);
            imshow(app.Images(1).Image, 'Parent', app.UIAxes);
            
            %mask initialization
            app.MaskNuclei = zeros(size(app.Images(1).Image,1), size(app.Images(1).Image, 2), app.numberOfImages);
            app.MaskArtefact = zeros(size(app.Images(1).Image,1), size(app.Images(1).Image, 2), app.numberOfImages);
            app.MaskSpot = zeros(size(app.Images(1).Image,1), size(app.Images(1).Image, 2), app.numberOfImages);
            app.MaskBcg = zeros(size(app.Images(1).Image,1), size(app.Images(1).Image, 2), app.numberOfImages);
            
            %adjust slider
            app.SlideSlider.Limits = [1, app.numberOfImages];
            
        end

        % Selection changed function: CategoriesButtonGroup
        function CategoriesButtonGroupSelectionChanged(app, event)
            selectedButton = app.CategoriesButtonGroup.SelectedObject.Text;
            app.Category = selectedButton;
            updateDisplay(app)
        end

        % Selection changed function: DrawingoptionsButtonGroup
        function DrawingoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DrawingoptionsButtonGroup.SelectedObject.Text;
            app.drawOptions = selectedButton;
        end

        % Button pushed function: SavemaskButton
        function SavemaskButtonPushed(app, event)
            ext = '.mat';
            [file, path] = uiputfile(ext);
            filename = fullfile(path, file);

            Masks = cell(4,1);
            Masks{1} = app.MaskNuclei;
            Masks{2} = app.MaskSpot;
            Masks{3} = app.MaskArtefact;
            Masks{4} = app.MaskBcg;
            
            save(filename, 'Masks', '-v7.3');
        end

        % Button pushed function: LabelallimagesButton
        function LabelallimagesButtonPushed(app, event)
            app.Category = 'background';
            app.backgroundButton.Value = 1;          
            
            hWaitBar = waitbar(0,'Work in progress','Name', 'Please wait');
            for i = 1:app.numberOfImages
                %automatic background labelling
                I = app.Images(i).Image;
                BackgroundLabel = labelBackground(app, I);
                app.MaskBcg(:,:,i) = BackgroundLabel;
                waitbar(i/app.numberOfImages)
            end
            delete(hWaitBar);

            updateDisplay(app);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.threshold = value;
        end

        % Button pushed function: StartdrawingButton
        function StartdrawingButtonPushed(app, event)
            drawMask(app)
        end

        % Value changed function: SlideSlider
        function SlideSliderValueChanged(app, event)
            value = round(app.SlideSlider.Value);
            app.currentImage = value;
            updateDisplay(app);
        end

        % Button pushed function: LoadmaskButton
        function LoadmaskButtonPushed(app, event)
            loadMask(app)
        end

        % Button pushed function: MakecorrectionsButton
        function MakecorrectionsButtonPushed(app, event)
            %app.MakecorrectionsButton.Enable = 'off';
            app.ImageLabelerUIFigure.Visible = 'off';
            image = app.Images(app.currentImage).Image;
            bcgMask = app.MaskBcg(:,:, app.currentImage);
            
            %Open the options dialog and pass inputs
            app.BackgroundCorrectionApp = BackgroundCorrection(app, image, bcgMask);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLabelerUIFigure = uifigure('Visible', 'off');
            app.ImageLabelerUIFigure.Name = 'Image Labeler';
            app.OptionsPanel = uipanel(app.ImageLabelerUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.LoadimageButton = uibutton(app.OptionsPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.BackgroundlabelingPanel = uipanel(app.OptionsPanel);
            app.BackgroundlabelingPanel.Title = 'Background labeling';
            app.LabelallimagesButton = uibutton(app.BackgroundlabelingPanel, 'push');
            app.LabelallimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LabelallimagesButtonPushed, true);
            app.LabelallimagesButton.Text = 'Label all images';
            app.ThresholdSliderLabel = uilabel(app.BackgroundlabelingPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.BackgroundlabelingPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Value = 0.1;
            app.MakecorrectionsButton = uibutton(app.BackgroundlabelingPanel, 'push');
            app.MakecorrectionsButton.ButtonPushedFcn = createCallbackFcn(app, @MakecorrectionsButtonPushed, true);
            app.MakecorrectionsButton.Text = 'Make corrections';
            app.SavemaskButton = uibutton(app.OptionsPanel, 'push');
            app.SavemaskButton.ButtonPushedFcn = createCallbackFcn(app, @SavemaskButtonPushed, true);
            app.SavemaskButton.Text = 'Save mask';
            app.CategoriesButtonGroup = uibuttongroup(app.OptionsPanel);
            app.CategoriesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CategoriesButtonGroupSelectionChanged, true);
            app.CategoriesButtonGroup.Title = 'Categories';
            app.nucleiButton = uiradiobutton(app.CategoriesButtonGroup);
            app.nucleiButton.Text = 'nuclei';
            app.nucleiButton.Value = true;
            app.spotButton = uiradiobutton(app.CategoriesButtonGroup);
            app.spotButton.Text = 'spot';
            app.artefactButton = uiradiobutton(app.CategoriesButtonGroup);
            app.artefactButton.Text = 'artefact';
            app.backgroundButton = uiradiobutton(app.CategoriesButtonGroup);
            app.backgroundButton.Text = 'background';
            app.StartdrawingButton = uibutton(app.OptionsPanel, 'push');
            app.StartdrawingButton.ButtonPushedFcn = createCallbackFcn(app, @StartdrawingButtonPushed, true);
            app.StartdrawingButton.Text = 'Start drawing';
            app.LoadmaskButton = uibutton(app.OptionsPanel, 'push');
            app.LoadmaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmaskButtonPushed, true);
            app.LoadmaskButton.Text = 'Load mask';
            app.DrawingoptionsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DrawingoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrawingoptionsButtonGroupSelectionChanged, true);
            app.DrawingoptionsButtonGroup.Title = 'Drawing options';
            app.drawButton = uiradiobutton(app.DrawingoptionsButtonGroup);
            app.drawButton.Text = 'draw';
            app.drawButton.Value = true;
            app.eraseButton = uiradiobutton(app.DrawingoptionsButtonGroup);
            app.eraseButton.Text = 'erase';
            app.UIAxes = uiaxes(app.ImageLabelerUIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.ImageLabelerUIFigure);
            title(app.UIAxes2, 'Preview')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.SlideSliderLabel = uilabel(app.ImageLabelerUIFigure);
            app.SlideSliderLabel.Text = 'Slide';
            app.SlideSlider = uislider(app.ImageLabelerUIFigure);
            app.SlideSlider.ValueChangedFcn = createCallbackFcn(app, @SlideSliderValueChanged, true);
            % Show the figure after all components are created
            app.ImageLabelerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageLabeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLabelerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLabelerUIFigure)
        end
    end
end